classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        NghiemTab                      matlab.ui.container.Tab
        KhongphnlynghimEditFieldLabel  matlab.ui.control.Label
        Hamlap                         matlab.ui.control.EditField
        HmlpLabel                      matlab.ui.control.Label
        Note                           matlab.ui.control.EditField
        GHICHEditFieldLabel            matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        ChnphngphpDropDownLabel        matlab.ui.control.Label
        Result                         matlab.ui.control.EditField
        NghimEditFieldLabel            matlab.ui.control.Label
        Solanlap                       matlab.ui.control.EditField
        SlnlpEditFieldLabel            matlab.ui.control.Label
        Epsilon                        matlab.ui.control.NumericEditField
        SaisEditFieldLabel             matlab.ui.control.Label
        B                              matlab.ui.control.NumericEditField
        BEditFieldLabel                matlab.ui.control.Label
        A                              matlab.ui.control.NumericEditField
        AEditFieldLabel                matlab.ui.control.Label
        Func                           matlab.ui.control.EditField
        NhphmLabel                     matlab.ui.control.Label
        TimnghiemButton                matlab.ui.control.Button
        DngchophngphplpnLabel          matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        NoisuyTab                      matlab.ui.container.Tab
        Giatrinoisuy                   matlab.ui.control.NumericEditField
        GitrcnnisuyEditFieldLabel      matlab.ui.control.Label
        Y_Noisuy                       matlab.ui.control.EditField
        DliuYLabel_2                   matlab.ui.control.Label
        X_Noisuy                       matlab.ui.control.EditField
        DliuXLabel_2                   matlab.ui.control.Label
        Dathuc_Noisuy                  matlab.ui.control.EditField
        athcnisuyLabel                 matlab.ui.control.Label
        DrawButton_Noisuy              matlab.ui.control.Button
        Phuongphap_Noisuy              matlab.ui.container.ButtonGroup
        NoisuyLagrangeButton           matlab.ui.control.ToggleButton
        NoisuyNewtonButton             matlab.ui.control.ToggleButton
        Result_Noisuy                  matlab.ui.control.NumericEditField
        KtqunisuyLabel                 matlab.ui.control.Label
        UIAxes_Noisuy                  matlab.ui.control.UIAxes
        HoiquyTab                      matlab.ui.container.Tab
        Giatri_Hoiquy                  matlab.ui.control.NumericEditField
        GitrcndonLabel                 matlab.ui.control.Label
        KhongphnlynghimEditFieldLabel_2  matlab.ui.control.Label
        Y_Hoiquy                       matlab.ui.control.EditField
        YLabel                         matlab.ui.control.Label
        X_Hoiquy                       matlab.ui.control.EditField
        XLabel                         matlab.ui.control.Label
        Dathuc_Hoiquy                  matlab.ui.control.EditField
        athchiquyLabel                 matlab.ui.control.Label
        Result_Hoiquy                  matlab.ui.control.NumericEditField
        KtquhiquyLabel                 matlab.ui.control.Label
        DrawButton_Hoiquy              matlab.ui.control.Button
        PhuongPhap_Hoiquy              matlab.ui.container.ButtonGroup
        HammuButton                    matlab.ui.control.ToggleButton
        HammuEButton                   matlab.ui.control.ToggleButton
        TuyentinhButton                matlab.ui.control.ToggleButton
        UIAxes_Hoiquy                  matlab.ui.control.UIAxes
        DaohamTab                      matlab.ui.container.Tab
        Result_Daoham                  matlab.ui.control.EditField
        KtquohmEditFieldLabel          matlab.ui.control.Label
        Saiso                          matlab.ui.control.NumericEditField
        TinliChn1Oh2Oh2TrungtmChn1Oh2hoc2Oh4Label  matlab.ui.control.Label
        DulieuY_Daoham                 matlab.ui.control.EditField
        DliuYLabel                     matlab.ui.control.Label
        DulieuX_Daoham                 matlab.ui.control.EditField
        DliuXLabel                     matlab.ui.control.Label
        Giatricantinh                  matlab.ui.control.NumericEditField
        GitrcntnhohmLabel              matlab.ui.control.Label
        Buoc_h                         matlab.ui.control.NumericEditField
        BcnhyLabel                     matlab.ui.control.Label
        Hamso_Daoham                   matlab.ui.control.EditField
        HmsLabel_2                     matlab.ui.control.Label
        HoacLabel_3                    matlab.ui.control.Label
        PhuongphapDaohamButtonGroup    matlab.ui.container.ButtonGroup
        trungtamButton                 matlab.ui.control.Button
        luiButton                      matlab.ui.control.Button
        tienButton                     matlab.ui.control.Button
        TichphanTab                    matlab.ui.container.Tab
        cantren                        matlab.ui.control.NumericEditField
        NhpcntrnLabel                  matlab.ui.control.Label
        x                              matlab.ui.control.EditField
        NhpxLabel                      matlab.ui.control.Label
        y                              matlab.ui.control.EditField
        NhpyLabel                      matlab.ui.control.Label
        canduoi                        matlab.ui.control.NumericEditField
        NhpcndiLabel                   matlab.ui.control.Label
        kq                             matlab.ui.control.NumericEditField
        KtqutchphnLabel                matlab.ui.control.Label
        n                              matlab.ui.control.NumericEditField
        NhpNonchiaLabel                matlab.ui.control.Label
        ham                            matlab.ui.control.EditField
        HmsLabel                       matlab.ui.control.Label
        HoacLabel_2                    matlab.ui.control.Label
        PhuongphapTichphanButtonGroup  matlab.ui.container.ButtonGroup
        HoacLabel_6                    matlab.ui.control.Label
        HoacLabel_5                    matlab.ui.control.Label
        HoacLabel_4                    matlab.ui.control.Label
        simpson38                      matlab.ui.control.Button
        simpson13                      matlab.ui.control.Button
        hinhthang                      matlab.ui.control.Button
        GioithieunhomTab               matlab.ui.container.Tab
        TThien                         matlab.ui.control.Image
        TenAThien                      matlab.ui.control.Label
        TenTThien                      matlab.ui.control.Label
        TenATuan                       matlab.ui.control.Label
        TenTTuan                       matlab.ui.control.Label
        TenChan                        matlab.ui.control.Label
        TenVinh                        matlab.ui.control.Label
        AThien                         matlab.ui.control.Image
        TTuan                          matlab.ui.control.Image
        ATuan                          matlab.ui.control.Image
        Chan                           matlab.ui.control.Image
        Vinh                           matlab.ui.control.Image
        TenNhom                        matlab.ui.control.Label
    end


    methods (Access = private)
        
        % Hàm xử lý dữ liệu đầu vào (tách dữ liệu từ chuỗi)
        function data = ParseInputData(~, inputStr)
            inputStr = strrep(inputStr, ';', ','); % Thay thế dấu ; thành dấu ,
            inputStr = strrep(inputStr, ' ', ','); % Thay dấu cách bằng dấu ,
            splitData = strsplit(inputStr, ',');   % Tách chuỗi bằng dấu ,
            data = str2double(splitData);         % Chuyển sang dạng số
            data = data(~isnan(data));            % Loại bỏ các giá trị không hợp lệ
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TimnghiemButton
        function TimnghiemButtonPushed(app, event)
             a = app.A.Value;
            b = app.B.Value;
            fp = app.Hamlap.Value;
            fx = app.Func.Value;
            saiso = app.Epsilon.Value;  
                
            switch app.DropDown.Value
                case 'Chia đôi'
                    [m,n1] = chiadoi(fx, a, b, saiso);
                    % Nghiệm gần đúng của phương trình là m (hoặc a)
                    app.Result.Value = num2str(m);
                    app.Solanlap.Value = num2str(n1);
                    app.Note.Value = 'Thuật toán tìm được nghiệm gần đúng như trên.';
                    
                    % Vẽ đồ thị
                    veDoThi(app,fx);
            
                case 'Lặp đơn'
                    [x2, n2, check] = lap(fx, fp, a, b, saiso);
                    if check == 1
                        % Nghiệm gần đúng
                        app.Result.Value = num2str(x2);
                        app.Solanlap.Value = num2str(n2);
                        app.Note.Value = 'Hàm lặp này THỎA điều kiện lặp';
                    else
                        app.Note.Value = 'Hàm lặp này KHÔNG THỎA điều kiện lặp';
                        app.Result.Value = '';
                        app.Solanlap.Value = '';
                    end
                    % Vẽ đồ thị
                    veDoThi(app,fx);

            
                case 'Tiếp tuyến'
                    [x3, n3, check] = tieptuyen(fx, a, b, saiso);
                    if check == 1
                        app.Result.Value = num2str(x3);
                        app.Solanlap.Value = num2str(n3);
                    elseif check == 2
                        app.Note.Value = 'Thuật toán vượt quá số vòng lặp tối đa.';
                        app.Result.Value = '';
                        app.Solanlap.Value = '';
                    else
                        app.Note.Value = 'Hàm số KHÔNG THÕA điều kiện hội tụ.';
                        app.Result.Value = '';
                        app.Solanlap.Value = '';
                    end
                    % Vẽ đồ thị
                    veDoThi(app,fx);
            end

        end

        % Button pushed function: DrawButton_Hoiquy
        function DrawButton_HoiquyPushed(app, event)
            % Đọc và xử lý dữ liệu đầu vào
            X = app.ParseInputData(app.X_Hoiquy.Value); % Lấy chuỗi các giá trị từ khối X và xử lý sao đó lưu vào X
            Y = app.ParseInputData(app.Y_Hoiquy.Value); % Lấy chuỗi các giá trị từ khối Y và xử lý sao đó lưu vào Y
            
            % Dự đoán giá trị dựa trên hồi quy
            x_predict = app.Giatri_Hoiquy.Value; % Giá trị X cần dự đoán  
            
            % Chọn phương pháp hồi quy và tính toán       
            switch app.PhuongPhap_Hoiquy.SelectedObject.Text
                case 'Tuyến tính'
                    [b, a] = HoiQuyTuyenTinh(X, Y);
                    Y_pred = b * x_predict + a;
                    equation = sprintf('y = %.4fx + %.4f', b, a);
                    Y_poly = b * X + a;
                    
                case 'Hàm mũ e'     
                    logY = log(Y);
                    [a1, a0] = HoiQuyTuyenTinh(X, logY);
                    a = exp(a0);
                    b = a1;
                    Y_pred = a * exp(b * x_predict);
                    equation = sprintf('y = %.4fe^(%.4fx)', a, b);
                    Y_poly = a * exp(b.*X);
                
                case 'Hàm mũ'
                    logX = log(X);
                    logY = log(Y);
                    [a1, a0] = HoiQuyTuyenTinh(logX, logY); 
                    b = a1;
                    a = exp(a0);
                    Y_pred = a.*x_predict.^b;
                    equation = sprintf('y = %.4f * x^%.4f', a, b);
                    Y_poly = a.*X.^b;
            end
            
            %Hiển thị giá trị dự đoán lên khối
            app.Result_Hoiquy.Value = Y_pred;
            
            %Hiển thị đa thức lên khối
            app.Dathuc_Hoiquy.Value = equation; 
            
                    
            % Vẽ các điểm dữ liệu thực tế
            plot(app.UIAxes_Hoiquy, X, Y, 'bo', 'LineWidth', 1.5, 'MarkerSize', 8, 'DisplayName', 'Dữ liệu thực tế'); % Dùng dấu tròn xanh cho dữ liệu thực tế
            hold(app.UIAxes_Hoiquy, 'on'); % Giữ lại đồ thị để vẽ thêm
            
            % Vẽ đường hồi quy dự đoán
            plot(app.UIAxes_Hoiquy, X, Y_poly, 'r-', 'LineWidth', 1.5, 'DisplayName', 'Đường hồi quy'); % Đường hồi quy là đường đỏ
            
            % Cài đặt đồ thị
            xlabel(app.UIAxes_Hoiquy, 'X'); % Trục X
            ylabel(app.UIAxes_Hoiquy, 'Y'); % Trục Y
            title(app.UIAxes_Hoiquy, 'Đồ thị hồi quy'); % Tiêu đề
            grid(app.UIAxes_Hoiquy, 'on'); % Hiển thị lưới
            
            % Hiển thị chú thích
            legend(app.UIAxes_Hoiquy, 'show'); % Chú thích đặt ở vị trí tốt nhất
            hold(app.UIAxes_Hoiquy, 'off');
        end

        % Button pushed function: hinhthang
        function hinhthangButtonPushed(app, event)
            x1 = app.x.Value;
            y1 = app.y.Value;
            ham1 = app.ham.Value;
            N = app.n.Value;
            c = app.canduoi.Value;
            d = app.cantren.Value;
            I1 = TichPhanHinhThang(x1,y1,ham1,N,c,d);
            app.kq.Value = I1;
        end

        % Button pushed function: simpson13
        function simpson13ButtonPushed(app, event)
            x2 = app.x.Value;
            y2 = app.y.Value;
            ham2 = app.ham.Value;
            N = app.n.Value;
            c = app.canduoi.Value;
            d = app.cantren.Value;
            I2 = TichPhanSimpson13(x2,y2,ham2,N,c,d);
            app.kq.Value = I2;
        end

        % Button pushed function: simpson38
        function simpson38ButtonPushed(app, event)
            x3 = app.x.Value;
            y3 = app.y.Value;
            ham3 = app.ham.Value;
            N = app.n.Value;
            c = app.canduoi.Value;
            d = app.cantren.Value;
            I3 = TichPhanSimpson38(x3,y3,ham3,N,c,d);
            app.kq.Value = I3;
        end

        % Button pushed function: tienButton
        function tienButtonPushed(app, event)
            xi_1 = app.DulieuX_Daoham.Value;
            yi_1 = app.DulieuY_Daoham.Value;
            func_str_1 = app.Hamso_Daoham.Value;
            x1 = app.Giatricantinh.Value;
            h = app.Buoc_h.Value;
            saiso = app.Saiso.Value;
            
            [result,check] = Xapxitien(func_str_1, x1, xi_1, yi_1, h, saiso);
            if check == 1
                app.Result_Daoham.Value = "Dữ liệu x và y KHÔNG CÙNG ĐỘ DÀI";
            elseif check == 2
                app.Result_Daoham.Value = "Chọn KHÔNG ĐÚNG sai số cắt cụt";
            else
                % Gan ket qua vao Edit Field
                app.Result_Daoham.Value = num2str(result);
            end
        end

        % Button pushed function: luiButton
        function luiButtonPushed(app, event)
            xi_2 = app.DulieuX_Daoham.Value;
            yi_2 = app.DulieuY_Daoham.Value;
            func_str_2 = app.Hamso_Daoham.Value;
            x2 = app.Giatricantinh.Value;
            h = app.Buoc_h.Value;
            saiso = app.Saiso.Value;
            
            [result,check] = Xapxilui(func_str_2, x2, xi_2, yi_2, h, saiso);
            if check == 1
                app.Result_Daoham.Value = "Dữ liệu x và y KHÔNG CÙNG ĐỘ DÀI";
            elseif check == 2
                app.Result_Daoham.Value = "Chọn KHÔNG ĐÚNG sai số cắt cụt";
            else
                % Gan ket qua vao Edit Field
                app.Result_Daoham.Value = num2str(result);
            end
        end

        % Button pushed function: trungtamButton
        function trungtamButtonPushed(app, event)
             xi_3 = app.DulieuX_Daoham.Value;
            yi_3 = app.DulieuY_Daoham.Value;
            func_str_3 = app.Hamso_Daoham.Value;
            x3 = app.Giatricantinh.Value;
            h = app.Buoc_h.Value;
            saiso = app.Saiso.Value;
            
            [result,check] = Xapxitrungtam(func_str_3, x3, xi_3, yi_3, h, saiso);
            if check == 1
                app.Result_Daoham.Value = "Dữ liệu x và y KHÔNG CÙNG ĐỘ DÀI     ";
            elseif check == 2
                app.Result_Daoham.Value = "Chọn KHÔNG ĐÚNG sai số cắt cụt";
            else
                % Gan ket qua vao Edit Field
                app.Result_Daoham.Value = num2str(result);
            end
        end

        % Button pushed function: DrawButton_Noisuy
        function DrawButton_NoisuyPushed(app, event)

            % Đọc và xử lý dữ liệu đầu vào
            xi = app.ParseInputData(app.X_Noisuy.Value); % Lấy chuỗi các giá trị từ khối X và xử lý sao đó lưu vào X
            yi = app.ParseInputData(app.Y_Noisuy.Value); % Lấy chuỗi các giá trị từ khối Y và xử lý sao đó lưu vào Y
            
            Giatri_Noisuy = app.Giatrinoisuy.Value ;
            % Kiểm tra độ dài của xi và yi
            if length(xi) ~= length(yi)
                app.Dathuc_Noisuy.Value = 'Độ dài xi và yi không khớp!';
                return;
            end
            
            % Chọn phương pháp hồi quy và tính toán       
            switch app.Phuongphap_Noisuy.SelectedObject.Text
                case 'Nội suy Newton'
                    syms X; %Khai báo biến symbolic để kết quả được trả ra là dạng đa thức
                    P = Newton(xi, yi, X);
                    
                    % Hien thi da thuc duoi dang khai trien
                    result = vpa(expand(P), 5);
                    app.Dathuc_Noisuy.Value = char(result); %Đưa về dạng ký tự và gán vào
                    noisuy_value = double(subs(result, X, Giatri_Noisuy));% Tính giá trị nội suy
                    app.Result_Noisuy.Value = (noisuy_value); % Xuất kết quả nội suy ra giao diện
                    
                case 'Nội suy Lagrange'     
                    syms X; %Khai báo biến symbolic để kết quả được trả ra là dạng đa thức
                    P = Lagrange(xi, yi, X); %Nội suy Lagrange
                    
                    % Chuyen ve dang chuan a*x^3 + b*x^2 + c*x + d
                    % p_expanded = expand(P);
                    
                    % Hien thi da thuc duoi dang khai trien
                    result = vpa(expand(P), 5);
                    app.Dathuc_Noisuy.Value = char(result); %Đưa về dạng ký tự và gán vào
                    noisuy_value = double(subs(result, X, Giatri_Noisuy)); % Tính giá trị nội suy
                    app.Result_Noisuy.Value = (noisuy_value); % Xuất kết quả nội suy ra giao diện    
            end
            
            % Vẽ đồ thị trên UIAxes_Noisuy
            fplot(app.UIAxes_Noisuy, result , [min(xi) - 1, max(xi) + 1], 'b', 'LineWidth', 1.5); % Đường nội suy
            hold(app.UIAxes_Noisuy, 'on');
            scatter(app.UIAxes_Noisuy, xi, yi, 50, 'r', 'filled'); % Các điểm dữ liệu
            scatter(app.UIAxes_Noisuy, Giatri_Noisuy, noisuy_value, 100, 'g', 'filled'); % Điểm được nội suy
            legend(app.UIAxes_Noisuy, 'Đa thức nội suy', 'Dữ liệu đầu vào', 'Kết quả nội suy', 'Location', 'Best');
            xlabel(app.UIAxes_Noisuy, 'X');
            ylabel(app.UIAxes_Noisuy, 'Y');
            title(app.UIAxes_Noisuy, 'Đồ thị nội suy Lagrange');
            hold(app.UIAxes_Noisuy, 'off')
            grid(app.UIAxes_Noisuy,'on');   

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghiemTab = uitab(app.TabGroup);
            app.NghiemTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghiemTab);
            title(app.UIAxes, 'Đồ thị của hàm')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'y')
            app.DngchophngphplpnLabel = uilabel(app.NghiemTab);
            app.DngchophngphplpnLabel.Text = 'Dùng cho phương pháp lặp đơn';
            app.TimnghiemButton = uibutton(app.NghiemTab, 'push');
            app.TimnghiemButton.ButtonPushedFcn = createCallbackFcn(app, @TimnghiemButtonPushed, true);
            app.TimnghiemButton.Text = 'Tìm nghiệm';
            app.NhphmLabel = uilabel(app.NghiemTab);
            app.NhphmLabel.Text = 'Nhập hàm';
            app.Func = uieditfield(app.NghiemTab, 'text');
            app.Func.Value = 'x.^4 - 3*x.^2 +75*x - 10000';
            app.AEditFieldLabel = uilabel(app.NghiemTab);
            app.AEditFieldLabel.Text = 'A';
            app.A = uieditfield(app.NghiemTab, 'numeric');
            app.A.Value = -11;
            app.BEditFieldLabel = uilabel(app.NghiemTab);
            app.BEditFieldLabel.Text = 'B';
            app.B = uieditfield(app.NghiemTab, 'numeric');
            app.B.Value = -10;
            app.SaisEditFieldLabel = uilabel(app.NghiemTab);
            app.SaisEditFieldLabel.Text = 'Sai số';
            app.Epsilon = uieditfield(app.NghiemTab, 'numeric');
            app.Epsilon.Value = 0.01;
            app.SlnlpEditFieldLabel = uilabel(app.NghiemTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.Solanlap = uieditfield(app.NghiemTab, 'text');
            app.NghimEditFieldLabel = uilabel(app.NghiemTab);
            app.NghimEditFieldLabel.Text = 'Nghiệm';
            app.Result = uieditfield(app.NghiemTab, 'text');
            app.ChnphngphpDropDownLabel = uilabel(app.NghiemTab);
            app.ChnphngphpDropDownLabel.Text = 'Chọn phương pháp';
            app.DropDown = uidropdown(app.NghiemTab);
            app.DropDown.Items = {'Chia đôi', 'Lặp đơn', 'Tiếp tuyến'};
            app.DropDown.Value = 'Chia đôi';
            app.GHICHEditFieldLabel = uilabel(app.NghiemTab);
            app.GHICHEditFieldLabel.Text = 'GHI CHÚ';
            app.Note = uieditfield(app.NghiemTab, 'text');
            app.HmlpLabel = uilabel(app.NghiemTab);
            app.HmlpLabel.Text = 'Hàm lặp';
            app.Hamlap = uieditfield(app.NghiemTab, 'text');
            app.KhongphnlynghimEditFieldLabel = uilabel(app.NghiemTab);
            app.KhongphnlynghimEditFieldLabel.Text = 'Khoảng phân ly nghiệm';
            app.NoisuyTab = uitab(app.TabGroup);
            app.NoisuyTab.Title = 'Nội suy';
            app.UIAxes_Noisuy = uiaxes(app.NoisuyTab);
            title(app.UIAxes_Noisuy, 'Đồ thị hàm số')
            xlabel(app.UIAxes_Noisuy, 'X')
            ylabel(app.UIAxes_Noisuy, 'Y')
            app.KtqunisuyLabel = uilabel(app.NoisuyTab);
            app.KtqunisuyLabel.Text = 'Kết quả nội suy';
            app.Result_Noisuy = uieditfield(app.NoisuyTab, 'numeric');
            app.Phuongphap_Noisuy = uibuttongroup(app.NoisuyTab);
            app.Phuongphap_Noisuy.Title = 'Phương pháp nội suy';
            app.NoisuyNewtonButton = uitogglebutton(app.Phuongphap_Noisuy);
            app.NoisuyNewtonButton.Text = 'Nội suy Newton';
            app.NoisuyNewtonButton.Value = true;
            app.NoisuyLagrangeButton = uitogglebutton(app.Phuongphap_Noisuy);
            app.NoisuyLagrangeButton.Text = 'Nội suy Lagrange';
            app.DrawButton_Noisuy = uibutton(app.NoisuyTab, 'push');
            app.DrawButton_Noisuy.ButtonPushedFcn = createCallbackFcn(app, @DrawButton_NoisuyPushed, true);
            app.DrawButton_Noisuy.Text = 'Chạy';
            app.athcnisuyLabel = uilabel(app.NoisuyTab);
            app.athcnisuyLabel.Text = 'Đa thức nội suy';
            app.Dathuc_Noisuy = uieditfield(app.NoisuyTab, 'text');
            app.DliuXLabel_2 = uilabel(app.NoisuyTab);
            app.DliuXLabel_2.Text = 'Dữ liệu X';
            app.X_Noisuy = uieditfield(app.NoisuyTab, 'text');
            app.X_Noisuy.Value = '-2 -1 1 2';
            app.DliuYLabel_2 = uilabel(app.NoisuyTab);
            app.DliuYLabel_2.Text = 'Dữ liệu Y';
            app.Y_Noisuy = uieditfield(app.NoisuyTab, 'text');
            app.Y_Noisuy.Value = '-6 0 0 6';
            app.GitrcnnisuyEditFieldLabel = uilabel(app.NoisuyTab);
            app.GitrcnnisuyEditFieldLabel.Text = 'Giá trị cần nội suy';
            app.Giatrinoisuy = uieditfield(app.NoisuyTab, 'numeric');
            app.Giatrinoisuy.Value = 1.5;
            app.HoiquyTab = uitab(app.TabGroup);
            app.HoiquyTab.Title = 'Hồi quy';
            app.UIAxes_Hoiquy = uiaxes(app.HoiquyTab);
            title(app.UIAxes_Hoiquy, 'Đồ thị hàm số')
            xlabel(app.UIAxes_Hoiquy, 'X')
            ylabel(app.UIAxes_Hoiquy, 'Y')
            app.PhuongPhap_Hoiquy = uibuttongroup(app.HoiquyTab);
            app.PhuongPhap_Hoiquy.Title = 'Phương pháp hồi quy';
            app.TuyentinhButton = uitogglebutton(app.PhuongPhap_Hoiquy);
            app.TuyentinhButton.Text = 'Tuyến tính';
            app.TuyentinhButton.Value = true;
            app.HammuEButton = uitogglebutton(app.PhuongPhap_Hoiquy);
            app.HammuEButton.Text = 'Hàm mũ e';
            app.HammuButton = uitogglebutton(app.PhuongPhap_Hoiquy);
            app.HammuButton.Text = 'Hàm mũ';
            app.DrawButton_Hoiquy = uibutton(app.HoiquyTab, 'push');
            app.DrawButton_Hoiquy.ButtonPushedFcn = createCallbackFcn(app, @DrawButton_HoiquyPushed, true);
            app.DrawButton_Hoiquy.Text = 'Chạy';
            app.KtquhiquyLabel = uilabel(app.HoiquyTab);
            app.KtquhiquyLabel.Text = 'Kết quả hồi quy';
            app.Result_Hoiquy = uieditfield(app.HoiquyTab, 'numeric');
            app.athchiquyLabel = uilabel(app.HoiquyTab);
            app.athchiquyLabel.Text = 'Đa thức hồi quy';
            app.Dathuc_Hoiquy = uieditfield(app.HoiquyTab, 'text');
            app.XLabel = uilabel(app.HoiquyTab);
            app.XLabel.Text = 'X';
            app.X_Hoiquy = uieditfield(app.HoiquyTab, 'text');
            app.X_Hoiquy.Value = '0.65, 0.75, 0.85, 0.95, 1.15';
            app.YLabel = uilabel(app.HoiquyTab);
            app.YLabel.Text = 'Y';
            app.Y_Hoiquy = uieditfield(app.HoiquyTab, 'text');
            app.Y_Hoiquy.Value = '0.96, 1.06, 1.17, 1.29, 1.58';
            app.KhongphnlynghimEditFieldLabel_2 = uilabel(app.HoiquyTab);
            app.KhongphnlynghimEditFieldLabel_2.Text = 'Nhập';
            app.GitrcndonLabel = uilabel(app.HoiquyTab);
            app.GitrcndonLabel.Text = 'Giá trị cần dự đoán';
            app.Giatri_Hoiquy = uieditfield(app.HoiquyTab, 'numeric');
            app.Giatri_Hoiquy.Value = 1;
            app.DaohamTab = uitab(app.TabGroup);
            app.DaohamTab.Title = 'Đạo hàm';
            app.PhuongphapDaohamButtonGroup = uibuttongroup(app.DaohamTab);
            app.PhuongphapDaohamButtonGroup.Title = 'Phương pháp đạo hàm';
            app.tienButton = uibutton(app.PhuongphapDaohamButtonGroup, 'push');
            app.tienButton.ButtonPushedFcn = createCallbackFcn(app, @tienButtonPushed, true);
            app.tienButton.Text = 'Xấp xỉ tiến';
            app.luiButton = uibutton(app.PhuongphapDaohamButtonGroup, 'push');
            app.luiButton.ButtonPushedFcn = createCallbackFcn(app, @luiButtonPushed, true);
            app.luiButton.Text = 'Xấp xỉ lùi';
            app.trungtamButton = uibutton(app.PhuongphapDaohamButtonGroup, 'push');
            app.trungtamButton.ButtonPushedFcn = createCallbackFcn(app, @trungtamButtonPushed, true);
            app.trungtamButton.Text = 'Xấp xỉ trung tâm';
            app.HoacLabel_3 = uilabel(app.DaohamTab);
            app.HoacLabel_3.Text = 'Hoặc';
            app.HmsLabel_2 = uilabel(app.DaohamTab);
            app.HmsLabel_2.Text = 'Hàm số ';
            app.Hamso_Daoham = uieditfield(app.DaohamTab, 'text');
            app.BcnhyLabel = uilabel(app.DaohamTab);
            app.BcnhyLabel.Text = 'Bước nhảy';
            app.Buoc_h = uieditfield(app.DaohamTab, 'numeric');
            app.Buoc_h.Value = 0.25;
            app.GitrcntnhohmLabel = uilabel(app.DaohamTab);
            app.GitrcntnhohmLabel.Text = 'Giá trị cần tính đạo hàm';
            app.Giatricantinh = uieditfield(app.DaohamTab, 'numeric');
            app.Giatricantinh.Value = 0.5;
            app.DliuXLabel = uilabel(app.DaohamTab);
            app.DliuXLabel.Text = 'Dữ liệu X';
            app.DulieuX_Daoham = uieditfield(app.DaohamTab, 'text');
            app.DulieuX_Daoham.Value = '0 0.25 0.5 0.75 1';
            app.DliuYLabel = uilabel(app.DaohamTab);
            app.DliuYLabel.Text = {'Dữ liệu Y'; ''};
            app.DulieuY_Daoham = uieditfield(app.DaohamTab, 'text');
            app.DulieuY_Daoham.Value = '1.2 1.1035156 0.925 0.6363281 0.2';
            app.TinliChn1Oh2Oh2TrungtmChn1Oh2hoc2Oh4Label = uilabel(app.DaohamTab);
            app.TinliChn1Oh2Oh2TrungtmChn1Oh2hoc2Oh4Label.Text = {'Tiến, lùi'; 'Chọn 1[O(h)], 2[O(h^2)]'; 'Trung tâm'; 'Chọn 1[O(h^2)] hoặc 2[O(h^4)]'; ''; ''; ''};
            app.Saiso = uieditfield(app.DaohamTab, 'numeric');
            app.Saiso.Value = 2;
            app.KtquohmEditFieldLabel = uilabel(app.DaohamTab);
            app.KtquohmEditFieldLabel.Text = 'Kết quả đạo hàm';
            app.Result_Daoham = uieditfield(app.DaohamTab, 'text');
            app.TichphanTab = uitab(app.TabGroup);
            app.TichphanTab.Title = 'Tích phân';
            app.PhuongphapTichphanButtonGroup = uibuttongroup(app.TichphanTab);
            app.PhuongphapTichphanButtonGroup.Title = 'Phương pháp tích phân';
            app.hinhthang = uibutton(app.PhuongphapTichphanButtonGroup, 'push');
            app.hinhthang.ButtonPushedFcn = createCallbackFcn(app, @hinhthangButtonPushed, true);
            app.hinhthang.Text = 'Hình thang';
            app.simpson13 = uibutton(app.PhuongphapTichphanButtonGroup, 'push');
            app.simpson13.ButtonPushedFcn = createCallbackFcn(app, @simpson13ButtonPushed, true);
            app.simpson13.Text = 'Simpson 1/3';
            app.simpson38 = uibutton(app.PhuongphapTichphanButtonGroup, 'push');
            app.simpson38.ButtonPushedFcn = createCallbackFcn(app, @simpson38ButtonPushed, true);
            app.simpson38.Text = 'Simpson 3/8';
            app.HoacLabel_4 = uilabel(app.PhuongphapTichphanButtonGroup);
            app.HoacLabel_4.Text = 'N tùy ý';
            app.HoacLabel_5 = uilabel(app.PhuongphapTichphanButtonGroup);
            app.HoacLabel_5.Text = 'N chẵn';
            app.HoacLabel_6 = uilabel(app.PhuongphapTichphanButtonGroup);
            app.HoacLabel_6.Text = 'N là bội của 3';
            app.HoacLabel_2 = uilabel(app.TichphanTab);
            app.HoacLabel_2.Text = 'Hoặc';
            app.HmsLabel = uilabel(app.TichphanTab);
            app.HmsLabel.Text = 'Hàm số ';
            app.ham = uieditfield(app.TichphanTab, 'text');
            app.ham.Value = '1./x';
            app.NhpNonchiaLabel = uilabel(app.TichphanTab);
            app.NhpNonchiaLabel.Text = 'Nhập N đoạn chia';
            app.n = uieditfield(app.TichphanTab, 'numeric');
            app.n.Value = 5;
            app.KtqutchphnLabel = uilabel(app.TichphanTab);
            app.KtqutchphnLabel.Text = 'Kết quả tích phân';
            app.kq = uieditfield(app.TichphanTab, 'numeric');
            app.NhpcndiLabel = uilabel(app.TichphanTab);
            app.NhpcndiLabel.Text = 'Nhập cận dưới';
            app.canduoi = uieditfield(app.TichphanTab, 'numeric');
            app.canduoi.Value = 1;
            app.NhpyLabel = uilabel(app.TichphanTab);
            app.NhpyLabel.Text = 'Nhập y';
            app.y = uieditfield(app.TichphanTab, 'text');
            app.y.Value = '1 1/2 1/3 1/4 1/5';
            app.NhpxLabel = uilabel(app.TichphanTab);
            app.NhpxLabel.Text = 'Nhập x';
            app.x = uieditfield(app.TichphanTab, 'text');
            app.x.Value = '1 2 3 4 5';
            app.NhpcntrnLabel = uilabel(app.TichphanTab);
            app.NhpcntrnLabel.Text = 'Nhập cận trên';
            app.cantren = uieditfield(app.TichphanTab, 'numeric');
            app.cantren.Value = 6;
            app.GioithieunhomTab = uitab(app.TabGroup);
            app.GioithieunhomTab.Title = 'Giới thiệu nhóm';
            app.TenNhom = uilabel(app.GioithieunhomTab);
            app.TenNhom.Text = '';
            app.Vinh = uiimage(app.GioithieunhomTab);
            app.Vinh.ImageSource = 'Vinh.png';
            app.Chan = uiimage(app.GioithieunhomTab);
            app.Chan.ImageSource = 'C.png';
            app.ATuan = uiimage(app.GioithieunhomTab);
            app.ATuan.ImageSource = 'T_Trg.png';
            app.TTuan = uiimage(app.GioithieunhomTab);
            app.TTuan.ImageSource = 'TTuan.png';
            app.AThien = uiimage(app.GioithieunhomTab);
            app.AThien.ImageSource = 'ATHIEN.png';
            app.TenVinh = uilabel(app.GioithieunhomTab);
            app.TenVinh.Text = {'Nguyễn Phúc Vĩnh'; '22207104'};
            app.TenChan = uilabel(app.GioithieunhomTab);
            app.TenChan.Text = {'Võ Minh Chấn'; '22207110'};
            app.TenTTuan = uilabel(app.GioithieunhomTab);
            app.TenTTuan.Text = {'Huỳnh Thái Tuấn'; '22207098'};
            app.TenATuan = uilabel(app.GioithieunhomTab);
            app.TenATuan.Text = {'Nguyễn Anh Tuấn'; '22207099'};
            app.TenTThien = uilabel(app.GioithieunhomTab);
            app.TenTThien.Text = {'Đinh Trọng Thiên'; '22207082'};
            app.TenAThien = uilabel(app.GioithieunhomTab);
            app.TenAThien.Text = {'Nguyễn Anh Thiện'; '22207121'};
            app.TThien = uiimage(app.GioithieunhomTab);
            app.TThien.ImageSource = 'THIEN.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end